classdef Reversi_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_1       matlab.ui.control.StateButton
        Button_2       matlab.ui.control.StateButton
        Button_3       matlab.ui.control.StateButton
        Button_4       matlab.ui.control.StateButton
        Button_8       matlab.ui.control.StateButton
        Button_7       matlab.ui.control.StateButton
        Button_6       matlab.ui.control.StateButton
        Button_5       matlab.ui.control.StateButton
        Button_16      matlab.ui.control.StateButton
        Button_15      matlab.ui.control.StateButton
        Button_14      matlab.ui.control.StateButton
        Button_13      matlab.ui.control.StateButton
        Button_12      matlab.ui.control.StateButton
        Button_11      matlab.ui.control.StateButton
        Button_10      matlab.ui.control.StateButton
        Button_9       matlab.ui.control.StateButton
        Button_32      matlab.ui.control.StateButton
        Button_31      matlab.ui.control.StateButton
        Button_30      matlab.ui.control.StateButton
        Button_29      matlab.ui.control.StateButton
        Button_28      matlab.ui.control.StateButton
        Button_27      matlab.ui.control.StateButton
        Button_26      matlab.ui.control.StateButton
        Button_24      matlab.ui.control.StateButton
        Button_23      matlab.ui.control.StateButton
        Button_22      matlab.ui.control.StateButton
        Button_21      matlab.ui.control.StateButton
        Button_20      matlab.ui.control.StateButton
        Button_19      matlab.ui.control.StateButton
        Button_18      matlab.ui.control.StateButton
        Button_25      matlab.ui.control.StateButton
        Button_17      matlab.ui.control.StateButton
        Button_33      matlab.ui.control.StateButton
        Button_34      matlab.ui.control.StateButton
        Button_41      matlab.ui.control.StateButton
        Button_42      matlab.ui.control.StateButton
        Button_35      matlab.ui.control.StateButton
        Button_43      matlab.ui.control.StateButton
        Button_49      matlab.ui.control.StateButton
        Button_50      matlab.ui.control.StateButton
        Button_51      matlab.ui.control.StateButton
        Button_36      matlab.ui.control.StateButton
        Button_44      matlab.ui.control.StateButton
        Button_52      matlab.ui.control.StateButton
        Button_37      matlab.ui.control.StateButton
        Button_45      matlab.ui.control.StateButton
        Button_53      matlab.ui.control.StateButton
        Button_57      matlab.ui.control.StateButton
        Button_58      matlab.ui.control.StateButton
        Button_59      matlab.ui.control.StateButton
        Button_60      matlab.ui.control.StateButton
        Button_61      matlab.ui.control.StateButton
        Button_38      matlab.ui.control.StateButton
        Button_46      matlab.ui.control.StateButton
        Button_54      matlab.ui.control.StateButton
        Button_62      matlab.ui.control.StateButton
        Button_39      matlab.ui.control.StateButton
        Button_47      matlab.ui.control.StateButton
        Button_55      matlab.ui.control.StateButton
        Button_63      matlab.ui.control.StateButton
        Button_40      matlab.ui.control.StateButton
        Button_48      matlab.ui.control.StateButton
        Button_56      matlab.ui.control.StateButton
        Button_64      matlab.ui.control.StateButton
        NewGameButton  matlab.ui.control.Button
    end


    properties (Access = private)
        numPossMove
        noPossibleMoveInARow
        B
        BoardStates
    end

    methods (Access = private)
    
        function drawBoard(app)
            board = app.B.BoardState';
            for i = 1 : 64
                if board(i) == 1
                    eval(strcat('app.Button_',num2str(i),'.BackgroundColor = [0,0,0];'));
                    eval(strcat('app.Button_',num2str(i),'.Enable = false;'));
                elseif board(i) == -1
                    eval(strcat('app.Button_',num2str(i),'.BackgroundColor = [1,1,1]'));
                    eval(strcat('app.Button_',num2str(i),'.Enable = false;'));
                else
                    eval(strcat('app.Button_',num2str(i),'.BackgroundColor = [0.5,0.5,0.5];'));
                    eval(strcat('app.Button_',num2str(i),'.Enable = true;'));
                end
            end
        end

        function playerTurn(app)
            %% Search Possible Moves
            possiblePositions = findPossibleMoves(app.B);
            app.numPossMove = size(possiblePositions,1);
            if app.numPossMove == 0 % No Possible Moves
                app.noPossibleMoveInARow = app.noPossibleMoveInARow + 1;
                app.B.isPlayer1Turn = not(app.B.isPlayer1Turn);
                if app.noPossibleMoveInARow >=2
                    app.gameEnd();
                end
            else
                %% Select Move
                app.noPossibleMoveInARow = 0;
                for i = 1 : 64
                    eval(strcat('app.Button_',num2str(i),'.Enable = false;'));
                end
                
                for m = 1 : app.numPossMove
                    eval(strcat('app.Button_',num2str((possiblePositions(m,1)-1)*8 + possiblePositions(m,2)),'.BackgroundColor = [1,0.8,0.8];'));
                    eval(strcat('app.Button_',num2str((possiblePositions(m,1)-1)*8 + possiblePositions(m,2)),'.Enable = true;'));
                end
            end
        end
    
        function aiTurn(app)
            rewardValueRatio = 0.3;
            inverseTemperature = 0.5;
            %% Search Possible Moves
            [possiblePositions] = findPossibleMoves(app.B);
            app.numPossMove = size(possiblePositions,1);
            if app.numPossMove == 0 % No Possible Moves
                app.noPossibleMoveInARow = app.noPossibleMoveInARow + 1;
                app.B.isPlayer1Turn = not(app.B.isPlayer1Turn);
                if app.noPossibleMoveInARow >=2
                    app.gameEnd();
                end
            else
                %% Select Move
                app.noPossibleMoveInARow = 0;
                
                rewardMoves = zeros(app.numPossMove,1);
                valueMoves = zeros(app.numPossMove,1);

                %% Check all possible moves
                for m = 1 : app.numPossMove
                    tempB = updateBoard(app.B, possiblePositions(m,:));
                    tempb = tempB.BoardState;
                    %% Calculate reward
                    rewardMoves(m) = sum(sum(tempb));
                    %% Calculate value
                    stateFound = false;
                    i = 1;
                    currentStageNumber = sum(sum(tempb == 0)) + 1;
                    while and(app.BoardStates{currentStageNumber}{i,3} ~=0, ~stateFound)
                        if tempb == app.BoardStates{currentStageNumber}{i,1}
                            valueMoves(m) = app.BoardStates{currentStageNumber}{i,2} / app.BoardStates{currentStageNumber}{i,3};
                            stateFound = true;
                        end
                        i = i + 1;
                    end
                end
                %% Evaluate score
                % scoreMoves : total score from the reward and value
                scoreMoves = rewardMoves*rewardValueRatio + valueMoves*(1-rewardValueRatio); 
                %% Select Move
                selectedMoveIndex = softmaxSel(scoreMoves * (-1), inverseTemperature);
                %% Update the board 
                app.B = updateBoard(app.B, possiblePositions(selectedMoveIndex, :));
                app.drawBoard();
                eval(strcat('app.Button_',num2str((possiblePositions(selectedMoveIndex,1)-1)*8 + possiblePositions(selectedMoveIndex,2)),'.BackgroundColor = [0.8,0.8,1];'));
                app.playerTurn();
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tempVar = load('BoardStates.mat','BoardStates');
            app.BoardStates = tempVar.BoardStates;
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.B = Board();
            app.B.Initialize();
            app.noPossibleMoveInARow = 0; % if >=2, P1 and P2 has no place to play. => end of the game
            app.drawBoard();
            app.playerTurn();
        end

        % Value changed function: Button_1, Button_10, Button_11, 
        % Button_12, Button_13, Button_14, Button_15, Button_16, 
        % Button_17, Button_18, Button_19, Button_2, Button_20, 
        % Button_21, Button_22, Button_23, Button_24, Button_25, 
        % Button_26, Button_27, Button_28, Button_29, Button_3, 
        % Button_30, Button_31, Button_32, Button_33, Button_34, 
        % Button_35, Button_36, Button_37, Button_38, Button_39, 
        % Button_4, Button_40, Button_41, Button_42, Button_43, 
        % Button_44, Button_45, Button_46, Button_47, Button_48, 
        % Button_49, Button_5, Button_50, Button_51, Button_52, 
        % Button_53, Button_54, Button_55, Button_56, Button_57, 
        % Button_58, Button_59, Button_6, Button_60, Button_61, 
        % Button_62, Button_63, Button_64, Button_7, Button_8, Button_9
        function Button_Clicked(app, event)
            buttonNum = str2double(event.Source.Text);
            eval(strcat('app.Button_',num2str(buttonNum),'.Value = 0;'));
            selMove = [floor(buttonNum/8)+1,rem(buttonNum-1,8)+1]';
            %% Update the board 
            app.B = updateBoard(app.B, selMove);
            app.drawBoard();
            drawnow;
            pause(1.5);
            app.aiTurn();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_4.Text = '4';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_8.Text = '8';
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_7.Text = '7';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_6.Text = '6';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_5.Text = '5';
            app.Button_16 = uibutton(app.UIFigure, 'state');
            app.Button_16.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_16.Text = '16';
            app.Button_15 = uibutton(app.UIFigure, 'state');
            app.Button_15.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_15.Text = '15';
            app.Button_14 = uibutton(app.UIFigure, 'state');
            app.Button_14.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_14.Text = '14';
            app.Button_13 = uibutton(app.UIFigure, 'state');
            app.Button_13.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_13.Text = '13';
            app.Button_12 = uibutton(app.UIFigure, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_12.Text = '12';
            app.Button_11 = uibutton(app.UIFigure, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_11.Text = '11';
            app.Button_10 = uibutton(app.UIFigure, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_10.Text = '10';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_9.Text = '9';
            app.Button_32 = uibutton(app.UIFigure, 'state');
            app.Button_32.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_32.Text = '32';
            app.Button_31 = uibutton(app.UIFigure, 'state');
            app.Button_31.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_31.Text = '31';
            app.Button_30 = uibutton(app.UIFigure, 'state');
            app.Button_30.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_30.Text = '30';
            app.Button_29 = uibutton(app.UIFigure, 'state');
            app.Button_29.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_29.Text = '29';
            app.Button_28 = uibutton(app.UIFigure, 'state');
            app.Button_28.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_28.Text = '28';
            app.Button_27 = uibutton(app.UIFigure, 'state');
            app.Button_27.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_27.Text = '27';
            app.Button_26 = uibutton(app.UIFigure, 'state');
            app.Button_26.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_26.Text = '26';
            app.Button_24 = uibutton(app.UIFigure, 'state');
            app.Button_24.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_24.Text = '24';
            app.Button_23 = uibutton(app.UIFigure, 'state');
            app.Button_23.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_23.Text = '23';
            app.Button_22 = uibutton(app.UIFigure, 'state');
            app.Button_22.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_22.Text = '22';
            app.Button_21 = uibutton(app.UIFigure, 'state');
            app.Button_21.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_21.Text = '21';
            app.Button_20 = uibutton(app.UIFigure, 'state');
            app.Button_20.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_20.Text = '20';
            app.Button_19 = uibutton(app.UIFigure, 'state');
            app.Button_19.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_19.Text = '19';
            app.Button_18 = uibutton(app.UIFigure, 'state');
            app.Button_18.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_18.Text = '18';
            app.Button_25 = uibutton(app.UIFigure, 'state');
            app.Button_25.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_25.Text = '25';
            app.Button_17 = uibutton(app.UIFigure, 'state');
            app.Button_17.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_17.Text = '17';
            app.Button_33 = uibutton(app.UIFigure, 'state');
            app.Button_33.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_33.Text = '33';
            app.Button_34 = uibutton(app.UIFigure, 'state');
            app.Button_34.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_34.Text = '34';
            app.Button_41 = uibutton(app.UIFigure, 'state');
            app.Button_41.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_41.Text = '41';
            app.Button_42 = uibutton(app.UIFigure, 'state');
            app.Button_42.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_42.Text = '42';
            app.Button_35 = uibutton(app.UIFigure, 'state');
            app.Button_35.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_35.Text = '35';
            app.Button_43 = uibutton(app.UIFigure, 'state');
            app.Button_43.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_43.Text = '43';
            app.Button_49 = uibutton(app.UIFigure, 'state');
            app.Button_49.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_49.Text = '49';
            app.Button_50 = uibutton(app.UIFigure, 'state');
            app.Button_50.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_50.Text = '50';
            app.Button_51 = uibutton(app.UIFigure, 'state');
            app.Button_51.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_51.Text = '51';
            app.Button_36 = uibutton(app.UIFigure, 'state');
            app.Button_36.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_36.Text = '36';
            app.Button_44 = uibutton(app.UIFigure, 'state');
            app.Button_44.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_44.Text = '44';
            app.Button_52 = uibutton(app.UIFigure, 'state');
            app.Button_52.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_52.Text = '52';
            app.Button_37 = uibutton(app.UIFigure, 'state');
            app.Button_37.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_37.Text = '37';
            app.Button_45 = uibutton(app.UIFigure, 'state');
            app.Button_45.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_45.Text = '45';
            app.Button_53 = uibutton(app.UIFigure, 'state');
            app.Button_53.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_53.Text = '53';
            app.Button_57 = uibutton(app.UIFigure, 'state');
            app.Button_57.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_57.Text = '57';
            app.Button_58 = uibutton(app.UIFigure, 'state');
            app.Button_58.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_58.Text = '58';
            app.Button_59 = uibutton(app.UIFigure, 'state');
            app.Button_59.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_59.Text = '59';
            app.Button_60 = uibutton(app.UIFigure, 'state');
            app.Button_60.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_60.Text = '60';
            app.Button_61 = uibutton(app.UIFigure, 'state');
            app.Button_61.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_61.Text = '61';
            app.Button_38 = uibutton(app.UIFigure, 'state');
            app.Button_38.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_38.Text = '38';
            app.Button_46 = uibutton(app.UIFigure, 'state');
            app.Button_46.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_46.Text = '46';
            app.Button_54 = uibutton(app.UIFigure, 'state');
            app.Button_54.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_54.Text = '54';
            app.Button_62 = uibutton(app.UIFigure, 'state');
            app.Button_62.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_62.Text = '62';
            app.Button_39 = uibutton(app.UIFigure, 'state');
            app.Button_39.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_39.Text = '39';
            app.Button_47 = uibutton(app.UIFigure, 'state');
            app.Button_47.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_47.Text = '47';
            app.Button_55 = uibutton(app.UIFigure, 'state');
            app.Button_55.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_55.Text = '55';
            app.Button_63 = uibutton(app.UIFigure, 'state');
            app.Button_63.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_63.Text = '63';
            app.Button_40 = uibutton(app.UIFigure, 'state');
            app.Button_40.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_40.Text = '40';
            app.Button_48 = uibutton(app.UIFigure, 'state');
            app.Button_48.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_48.Text = '48';
            app.Button_56 = uibutton(app.UIFigure, 'state');
            app.Button_56.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_56.Text = '56';
            app.Button_64 = uibutton(app.UIFigure, 'state');
            app.Button_64.ValueChangedFcn = createCallbackFcn(app, @Button_Clicked, true);
            app.Button_64.Text = '64';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Reversi_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end